classdef Openimage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DisplayImageTab               matlab.ui.container.Tab
        ImagePanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        ConvertButton                 matlab.ui.control.Button
        DicomDataPanel                matlab.ui.container.Panel
        BodyPartTextArea              matlab.ui.control.TextArea
        BodyPartTextAreaLabel         matlab.ui.control.Label
        ModalityTextArea              matlab.ui.control.TextArea
        ModalityTextAreaLabel         matlab.ui.control.Label
        PatienAgeTextArea             matlab.ui.control.TextArea
        PatienAgeTextAreaLabel        matlab.ui.control.Label
        PatientNameTextArea           matlab.ui.control.TextArea
        PatientNameTextAreaLabel      matlab.ui.control.Label
        ImageDataPanel                matlab.ui.container.Panel
        ImageColorTextArea            matlab.ui.control.TextArea
        ImageColorTextAreaLabel       matlab.ui.control.Label
        BitDepthTextArea              matlab.ui.control.TextArea
        BitDepthTextAreaLabel         matlab.ui.control.Label
        TotalSizeTextArea             matlab.ui.control.TextArea
        TotalSizeTextAreaLabel        matlab.ui.control.Label
        HeightTextArea                matlab.ui.control.TextArea
        HeightTextAreaLabel           matlab.ui.control.Label
        WidthTextArea                 matlab.ui.control.TextArea
        WidthTextAreaLabel            matlab.ui.control.Label
        DicomInformationLabel         matlab.ui.control.Label
        ImageInformationLabel         matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        CreateWhiteImageTab           matlab.ui.container.Tab
        ColorPixelsButton             matlab.ui.control.Button
        CreatWhiteImageButton         matlab.ui.control.Button
        UIAxes18                      matlab.ui.control.UIAxes
        InterpolationTab              matlab.ui.container.Tab
        NewWidthEditField             matlab.ui.control.EditField
        NewWidthEditFieldLabel        matlab.ui.control.Label
        NewHeightEditField            matlab.ui.control.EditField
        NewHeightEditFieldLabel       matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        BilinearButton                matlab.ui.control.RadioButton
        SelectInterpolaionButton      matlab.ui.control.RadioButton
        NeighbourButton               matlab.ui.control.RadioButton
        BilinearPanel                 matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        NearestNeighbourPanel         matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        EntertheFactorEditField       matlab.ui.control.NumericEditField
        EntertheFactorEditFieldLabel  matlab.ui.control.Label
        HistogramTab                  matlab.ui.container.Tab
        EqualizedimgButton            matlab.ui.control.Button
        NormalizedHistButton          matlab.ui.control.Button
        UIAxes10                      matlab.ui.control.UIAxes
        UIAxes9                       matlab.ui.control.UIAxes
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        FilteringTab                  matlab.ui.container.Tab
        FactorEditField_2             matlab.ui.control.NumericEditField
        FactorEditField_2Label        matlab.ui.control.Label
        KernelvalueEditField_2        matlab.ui.control.NumericEditField
        KernelvalueEditField_2Label   matlab.ui.control.Label
        FactorEditField               matlab.ui.control.EditField
        FactorEditFieldLabel          matlab.ui.control.Label
        KernelvalueEditField          matlab.ui.control.EditField
        KernelvalueEditFieldLabel     matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        UIAxes15                      matlab.ui.control.UIAxes
        UIAxes14                      matlab.ui.control.UIAxes
        UIAxes13                      matlab.ui.control.UIAxes
        FourierTab                    matlab.ui.container.Tab
        FourierTransformButton        matlab.ui.control.Button
        UIAxes17                      matlab.ui.control.UIAxes
        UIAxes16                      matlab.ui.control.UIAxes
        FourierFilteringTab           matlab.ui.container.Tab
        GuassianButton                matlab.ui.control.Button
        BoxFilterButton               matlab.ui.control.Button
        kernelsizeEditField           matlab.ui.control.NumericEditField
        kernelsizeEditFieldLabel      matlab.ui.control.Label
        UIAxes22                      matlab.ui.control.UIAxes
        UIAxes21                      matlab.ui.control.UIAxes
        UIAxes20                      matlab.ui.control.UIAxes
        UIAxes19                      matlab.ui.control.UIAxes
        NoiseTab                      matlab.ui.container.Tab
        CreateROIButton               matlab.ui.control.Button
        Panel2                        matlab.ui.container.Panel
        UIAxes29                      matlab.ui.control.UIAxes
        VarianceEditField             matlab.ui.control.NumericEditField
        VarianceEditFieldLabel        matlab.ui.control.Label
        MeanEditField                 matlab.ui.control.NumericEditField
        MeanEditFieldLabel            matlab.ui.control.Label
        ChooseNoiseDropDown           matlab.ui.control.DropDown
        ChooseNoiseDropDownLabel      matlab.ui.control.Label
        CreateImageButton             matlab.ui.control.Button
        DisplayHistogramButton        matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        UIAxes28                      matlab.ui.control.UIAxes
        UIAxes26                      matlab.ui.control.UIAxes
        BackProjectionTab             matlab.ui.container.Tab
        CreatePhantomButton           matlab.ui.control.Button
        UIAxes35                      matlab.ui.control.UIAxes
        UIAxes34                      matlab.ui.control.UIAxes
        UIAxes33                      matlab.ui.control.UIAxes
        UIAxes32                      matlab.ui.control.UIAxes
        UIAxes31                      matlab.ui.control.UIAxes
        UIAxes30                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img=255*ones(50,50,3); % Description
        Image1 % Description
        probability % Description
        histogram_values % Description
        colortype % Description
        wi % Description
       
        he % Description
        fft2im % Description
        test % Description
        new_img % Description
        Pixels % Description
        Final % Description
        c % Description
        noisy_image % Description
        ph % Description
        region % Description
        region1 % Description
    end
    
    methods (Access = public)
        
        function ctype = colortype1(app,bitpix,depth,format)
            if bitpix==1
                ctype='black&white'
            elseif depth==1   %|| format=='tif'
                ctype='geryscale'
            elseif format=='.tif'
                ctype='geryscale' 
            else
                ctype='RGB'
            end
            
        end
        
        
        function [Img_zoomed,NewHeight,NewWidth] = nearest_neighbour_zoom(app,Img, factor)
 
       [Height, Width] = size(Img);
       NewWidth = Width*factor;
       NewHeight = Height*factor;
      
       %generating image with new height and width
       %Img_zoomed = uint8(zeros(NewHeight, NewWidth));
       
       %assigning values of each pixel
       for i= 1:NewHeight
        for j= 1:NewWidth
          x = round(j/factor);
          y = round(i/factor);
         
          if(x<=0 || y<=0)
          x=1;
          y=1;
          
          end
          Img_zoomed(i, j) = Img(y, x);
        end
      end
      
       end


       function [Img_zoomed2,NewHeight,NewWidth] = bilenear_zoom(app,Img, factor)
 
        [Height, Width] = size(Img);
        
        NewHeight = factor*(Height); 
        NewWidth = factor*(Width);
        %Img_zoomed2 = uint8(zeros(NewHeight, NewWidth));


        % Padding the scaled image with 0 pixel value
        for i= 1:Height
         for j= 1:Width
          Img_zoomed2(factor*(i-1)+1, factor*(j-1)+1 ) = Img(i, j);
         end
        end


       % assigning values of each pixel
       for i= 0:factor:NewHeight-factor
       for j= 0:factor:NewWidth-factor
       firstpix = Img_zoomed2(i+1, j+1);
       secondpix = Img_zoomed2(i+1, j+1+factor);
       thirdpix = Img_zoomed2(i+1+factor, j+1);
       fourthpix = Img_zoomed2(i+1+factor, j+1+factor);
 
        a0 = firstpix;
        a1 = double((secondpix-firstpix)/factor);
        a2 = double((thirdpix-firstpix)/factor);
        a3 = double((fourthpix-thirdpix-secondpix+firstpix)/(factor*factor));
 
         for y= 0:factor
            for x= 0:factor
               Img_zoomed2(i+y+1, j+x+1) = a0 + a1*x + a2*y + a3*x*y;
            end
         end
      end
      end
      
 
      Img_zoomed2 = uint8(Img_zoomed2);
 
end


function [im_zoom,NewWidth,NewHeight]=Bilinear_Interpolation2(app,Img, factor)
         [Width, Height] = size(Img);  
                       
         NewWidth=factor*Width;
         NewHeight=factor*Height;

       for i=1:NewWidth
         x=i/factor;
         x1=floor(x);
         x2=ceil(x);
        if x1==0
           x1=1;
        end
        xint=rem(x,1);
        
        
       for j=1:NewHeight
        y=j/factor;
        y1=floor(y);
        y2=ceil(y);
        if y1==0
            y1=1;
        end
        yint=rem(y,1);
        
        firstpix=Img(x1,y1,:);
        secondpix=Img(x1,y2,:);
        thirdpix=Img(x2,y1,:);
        fourthpix=Img(x2,y2,:);
        
        R1=thirdpix*yint+firstpix*(1-yint);
        R2=fourthpix*yint+secondpix*(1-yint);
        
        im_zoom(i,j,:)=R1*xint+R2*(1-xint);
      end
      end
      
      im_zoom = uint8(im_zoom);
        end
        
        function Im = Spatial_Filtering(app,in,k)
          ker=ones((k)/k*k);
          I=double(in);
          m=size(ker,2);
          s=size(in);
          Im=zeros(s(1),s(2));
          str= (m+1)/2;
          stc = (m+1)/2;
          endr= s(1) - ((m-1)/2);
          endc= s(1) - ((m-1)/2);
         for i=str:endr
         for j=stc:endc
        window = I(i- ((m-1)/2): i+ ((m-1)/2), j-((m-1)/2): j+((m-1)/2));
        Im(i-((m-1)/2),j-((m-1)/2)) = sum(window.*ker,'all');
        
        end
         end
        Im = uint8(Im);
        %z=in-Im;
        %w=(z.*5)+in;
       
        %B = rescale(w,0,255);
        end
        
        function w= scale(img,maxium_val)
           w = (double(img) - double(min(img(:))))/(double(max(img(:))))-(double(min(img(:))))*double(maxium_val); 
        end
        
        function scaled_matrix = scaling(~,m)
            [h,w]=size(m);
            min_v=min(m);
            max_v=max(m);
            s=double(double(max_v-min_v)/double(intmax('uint8')));
            for i=1:h
                for j=1:w
                    m(i,j)=(double(m(i,j))-min_v)/s;
                end
            end
            scaled_matrix=m;
            
        end
        
       function mouseMove(objectHandle, eventData)
            cursorPoint = get(objectHandle.axes1, 'CurrentPoint');
            app.xCoord.Value = cursorPoint(1,1);
            app.yCoord.Value = cursorPoint(1,2);
        end
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
         
        end

        % Callback function
        function Button2Pushed(app, event)
            image=zeros(10,10,3); %initialize
            %image(:,:,:)=1;
            image(2,7:10,1)=1;   %Red (dark red)
            image(7:10,2,2)=1;   %Red (maximum value)
            
            
            image(:,1,:)=1;
            image(1,:,:)=1;
            image(7:10,3:10,:)=1;
            image(3:10,7:10,:)=1;
            image(1:6,1:6,:)=1;
           
            imshow(image,'parent',app.UIAxes2);
        
         
        end

        % Value changed function: ModalityTextArea
        function ModalityTextAreaValueChanged(app, event)

        end

        % Callback function
        function Button3Pushed(app, event)
          
        end

        % Button down function: DisplayImageTab
        function DisplayImageTabButtonDown(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed2(app, event)
         try 
           
            
            [filename pathname]=uigetfile({'*.jpg'; '*.dcm';'*.bmp';'*.jpeg';'*.tif';'*.*'},"Open file");
            fullpathname = strcat(pathname,filename);
            [pathname,filename,ext] = fileparts(fullpathname);
           
           
                app.Image1 = imread(fullpathname);
                
                
                info = imfinfo(fullpathname);
                filebytes = info.FileSize;
                imgebytes=info.Width*info.Height*info.BitDepth/8;
                compression=imgebytes/filebytes;
                pixels=info.Width*info.Height;
                bitpix=(filebytes*8)/pixels;
                
                %converting int data so it could be displayed in textarea
                
                height=int2str(info.Height);
                width=int2str(info.Width);
                bitdepth=int2str(info.BitDepth);
                app.colortype=(info.ColorType);
              
                app.he=info.Height;
                app.wi=info.Width;
                
                size=info.Height*info.Width*info.BitDepth;
                s=int2str(size);
               
                app.HeightTextArea.Value=height;
                
                app.WidthTextArea.Value=width;
                app.BitDepthTextArea.Value=bitdepth;
                app.TotalSizeTextArea.Value=s;
                %app.ImageColorTextArea.Value=info.ColorType;
                app.ImageColorTextArea.Value=colortype1(app,bitpix,bitdepth,ext);
               
               app.UIAxes.Position=[0 ,0, app.wi, app.he];
               
                imshow(app.Image1,'parent',app.UIAxes);
                
                app.ModalityTextArea.Value=('');
                app.PatientNameTextArea.Value=('');
                app.PatienAgeTextArea.Value=('');
                app.BodyPartTextArea.Value=('');
                
            if(ext == ".dcm" || ext==".DCM")
               info = dicominfo(fullpathname);
               Y = dicomread(info);
               
               
                height=int2str(info.Height);
                width=int2str(info.Width);
                bitdepth=int2str(info.BitDepth);
                
                size=info.Height*info.Width*info.BitDepth;
                s=int2str(size);
               
                app.HeightTextArea.Value=height;
                app.WidthTextArea.Value=width;
                app.BitDepthTextArea.Value=bitdepth;
                app.TotalSizeTextArea.Value=s;
                app.ImageColorTextArea.Value=colortype;


               app.ModalityTextArea.Value=info.Modality;
               
               %checking if fields exist before dislapying them
               
               if(isfield(info,'PatientName'))
               name = struct2array(info.PatientName);
               app.PatientNameTextArea.Value=name;
               else
               app.PatientNameTextArea.Value=('Field not found');
               end
               
               
               if(isfield(info,'PatientAge'))
               a=info.PatientAge;
               age = num2str(a);
               app.PatienAgeTextArea.Value=age;
               else
               app.PatienAgeTextArea.Value=('Field not found');
               end
               
               if(isfield(info,'BodyPartExamined'))
               app.BodyPartTextArea.Value=info.BodyPartExamined;
               else
               app.BodyPartTextArea.Value=('Field not found');  
               end


                imshow(Y,[],'Parent',app.UIAxes);
                
            
            end


        catch 
            %fig = uifigure;
            %uialert(fig,'Invalid Image','Error');
           warndlg('Invalid Image, Try Again','Error');
           
        end       
           
         %  if (bitpix~=1)
        %       app.Image1=im2double(app.Image1);
        %       r=app.Image1(:,:,1);
        %       g=app.Image1(:,:,2);
        %       b=app.Image1(:,:,3);
        %       grey_image=0.2989*r + 0.5870*g +0.114*b;
               %grey_image=uint82im(grey_image);
        %       imshow(grey_image,'parent',app.UIAxes);
               
         %  end


        end

        % Button pushed function: ColorPixelsButton
        function ColorPixelsButtonPushed2(app, event)
            %[w,h]=size(app.img);
            app.img(2,97,:)=[255,0,0];
            app.img(2,98,:)=[255,0,0];
            app.img(2,99,:)=[255,0,0];
            app.img(2,100,:)=[255,0,0];
            
            app.img(97,2,:)=[0,0,255];
            app.img(98,2,:)=[0,0,255];
            app.img(99,2,:)=[0,0,255];
            app.img(100,2,:)=[0,0,255];
            
            app.UIAxes18.Position=[37 ,714, 100, 100];
            imshow(app.img,'Parent',app.UIAxes18)
            %print('hi');
            %fig=app.UIFigure;
            %im=uiimage(fig,'ImageSource',app.img);
            %im.ScaleMethod='none';
            %app.UIAxes2.Position=[15 ,565, w, h];
            %imshow(app.img,'parent',app.UIAxes2)
        end

        % Callback function
        function Button2Pushed2(app, event)
            
        end

        % Button pushed function: CreatWhiteImageButton
        function CreatWhiteImageButtonPushed3(app, event)
           
            app.img=255*ones(100,100,3);
            %[w,h]=size(app.img);
            %fig=app.UIFigure;
            %im=uiimage(fig,'ImageSource',app.img);
            %im.ScaleMethod='none';
            
            app.UIAxes18.Position=[37 ,714, 100, 100];
            imshow(app.img,'Parent',app.UIAxes18)
            
            %set(app.UIAxes2,'Units','Pixels')
            %fig = uifigure;
            %im = uiimage(fig);
            %imag=uiimage(app.Image,'ScaleMethod','none','ImageSource','ima')
            %app.Image2 = app.img;
            %set(gcf,'position',[10 10 size(app.img,2) size(app.img,1)])
            %setpixelposition(gca,[0 0 size(app.img,2) size(app.img,1)])
            %zoom(app.UIAxes2,'off');
        end

        % Callback function
        function neighbourButtonPushed(app, event)
            
            factor=app.EntertheFactorEditField.Value;
           
            [Img_zoomed,h,w] = nearest_neighbour_zoom(app,app.Image1,factor);
            
            app.UIAxes3.Position=[0 ,0, w, h];
               
               
            imshow(Img_zoomed,'parent',app.UIAxes3);
           
        end

        % Callback function
        function Button2Pushed3(app, event)
           
            factor=app.EntertheFactorEditField.Value;
            [Img_zoomed3,h,w] = bilenear_zoom(app,app.Image1,factor);
            app.UIAxes4.Position=[-70 ,-70, w, h];
               
                
            imshow(Img_zoomed3,'parent',app.UIAxes4);
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
         try  
            if(app.colortype=="truecolor")
                    app.Image1=rgb2gray(app.Image1);
             end
             imshow(app.Image1,'parent',app.UIAxes);
         catch
            warndlg('please select an image first , Try Again','Error');
            
         end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if(selectedButton==app.NeighbourButton)
              factor=app.EntertheFactorEditField.Value;
              if(factor<=0)
                 imshow('','parent',app.UIAxes3); 
               else
              [Img_zoomed,h,w] = nearest_neighbour_zoom(app,app.Image1,factor);
              app.UIAxes3.Position=[0 ,0, w, h];
              height=int2str(h); 
              width=int2str(w);
              app.NewHeightEditField.Value=height;
              app.NewWidthEditField.Value=width;
              
              imshow(Img_zoomed,'parent',app.UIAxes3);
              end
            elseif(selectedButton==app.BilinearButton)
               factor=app.EntertheFactorEditField.Value;
               %[Img_zoomed3,h,w] = bilenear_zoom(app,app.Image1,factor);
               if(factor<=0)
                  imshow('','parent',app.UIAxes4);
               else
                  [Img_zoomed3,h,w]=Bilinear_Interpolation2(app,app.Image1,factor);
                  app.UIAxes4.Position=[0 ,0, w, h];
               
                
                 imshow(Img_zoomed3,'parent',app.UIAxes4);  
               end
               
            end
            
            
        end

        % Button pushed function: NormalizedHistButton
        function NormalizedHistButtonPushed(app, event)
            
            [rows,cols] = size(app.Image1); 
            pixels=rows*cols;
            
            app.histogram_values = zeros(1,256);
            app.probability = zeros(1,256);
            
            
            for i = 1:rows
            for j = 1:cols
                
            grylev = app.Image1(i,j) + 1 ;  % getting gray level of each pixel 
                                       % 1 was added to avoid error if i
                                       % have a zero value since indices
                                       % start from 1 while gray scale
                                       % starts from 0 
                                    
                                       
             %incrementing the intensity
            app.histogram_values(grylev) = app.histogram_values(grylev) + 1 ;
            app.probability(grylev)=app.histogram_values(grylev)/pixels;
           end
            end
            
           %app.UIAxes6.Position=[27 ,576, 255, 255];
           xaxis=0:255;   
           imshow(app.Image1,'parent',app.UIAxes9);
           bar(app.UIAxes10,xaxis,app.probability);
          
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: EqualizedimgButton
        function EqualizedimgButtonPushed(app, event)
            [rows,cols] = size(app.Image1); 
            Equalized_img=uint8(zeros(rows,cols));
            pixels=rows*cols;
            
            cum=zeros(1,256);
            output=zeros(1,256);
          
            sum=0;
            L=255;  %intensity levels - 1
            
           for i=1:size(app.probability,2) %looping on the columns of prob

           sum=sum+app.probability(i); %calculating cummulative prob
           cum(i)=sum;
           
           
           output(i)=round(cum(i)*L);

            end
            
            for i=1:rows
            for j=1:cols
            
            %grylev = app.Image1(i,j) + 1 ;
            Equalized_img(i,j)=output(app.Image1(i,j)+1);
            %Equalized_img(i,j)=output(grylev);

            end
            end 
            
            histogram_values_equ_img = zeros(1,256);
            probaility_equ_img = zeros(1,256);
            
            for i = 1:rows
            for j = 1:cols
            p = Equalized_img(i,j) + 1;
            histogram_values_equ_img(p) = histogram_values_equ_img(p)+1 ;
            probaility_equ_img(p)=histogram_values_equ_img(p)/pixels;
          
            end
            
            end
            xaxis=0:255;
            %bar(imhist(Equalized_img)/pixels,'histc')
            bar(app.UIAxes8,xaxis,probaility_equ_img);
           
            imshow(Equalized_img,'parent',app.UIAxes7);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.ChooseNoiseDropDown.Value;
            if value == 'Normailed histogram'
            [rows,cols] = size(app.Image1); 
            HIm=uint8(zeros(size(app.Image1,1),size(app.Image1,2)));
            pixels=rows*cols;
            app.histogram_values = zeros(256,1);
            app.probability = zeros(256,1);
            
            
            for i = 1:rows
            for j = 1:cols
            p = app.Image1(i,j);
            app.histogram_values(p+1) = app.histogram_values(p+1)+1 ;
            app.probability(p+1)=app.histogram_values(p+1)/pixels;
            %m=[0:255];
            end
            end
            bar(app.UIAxes6,app.probability, 'histc');


            end
        end

        % Callback function
        function DropDownOpening(app, event)
           fig = uifigure;
           dd = uidropdown(fig);
           dd.Items = {'','Normailed histogram','Equalized img'}; 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             kernal_size=app.KernelvalueEditField.Value;
             factor=(app.FactorEditField.Value);
           
             new_img = Spatial_Filtering(app,app.Image1,kernal_size);
             newimg=app.Image1-new_img;
             Imag=newimg.*factor+app.Image1;
             
             %eh=((app.Image1-en)*5)+app.Image1;
            
             %app.UIAxes11.Position=[0 ,0 app.wi, app.he];
             %imshow(f);
          %   if(eh==f)
          %       warndlg('true');
          %   else
          %       warndlg('false');
          %   end
             %imshow(eh,'parent',app.UIAxes13);
             imshow(new_img,'parent',app.UIAxes14);
             
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed4(app, event)
       try     
            kernal_size=app.KernelvalueEditField_2.Value;
            factor=app.FactorEditField_2.Value;
            %kernal_size=single(kernal_size);
            app.Image1=double(app.Image1);
            factor=double(factor);
            kernal=(ones(kernal_size))/(kernal_size*kernal_size);
           
            [M,N]=size(app.Image1);


            s=(kernal_size-1)/2;
            pad=zeros(M+kernal_size-1,N+kernal_size-1);
            for i=1:M
                for j=1:N
                    pad(i+s,j+s)=app.Image1(i,j);
                end
            end
            
            app.new_img=zeros(M,N);
           
             for i=1:size(pad,1)-(kernal_size-1)
                for j=1:size(pad,2)-(kernal_size-1)
                   
                    
                       filter=pad(i:i+(kernal_size-1),j:j+(kernal_size-1)).*kernal;
                       app.new_img(i,j)=sum(filter(:));
                      
                end
             end
              %app.new_img=double(app.new_img);
              %disp(size(app.I))
              %imshow(new_img,'parent',app.UIAxes14); 
             %app.Image1=double(app.Image1);
            imshow(app.new_img,'parent',app.UIAxes13);
            %imshow(app.new_img);
              newimg=(app.Image1-app.new_img);
              Image=newimg.*factor+app.Image1;
%              
              %imshow(app.Image1,'parent',app.UIAxes13);
              %ma=max(newimg);
              %w=app.scale(newimg);
              
              %scaling the image 
               mi=Image-min(Image);
               scaled=255*((mi)./max(mi));
               %sc=app.Image1-scaled;
               FinalImage=scaled.*factor+app.Image1;
               
               %imshow(FinalImage);
               imshow(FinalImage,'parent',app.UIAxes15);
             
             
%              en=(imfilter(app.Image1,kernal));
%              eh=((app.Image1-en)*5)+app.Image1;
%              if(eh==im)
%                  warndlg('true');
%             else
%                  warndlg('false');
%              end
               
               
       catch
           warndlg('Invalid number, Try Again','Error'); 
       end
        end

        % Button pushed function: FourierTransformButton
        function FourierTransformButtonPushed(app, event)
            app.fft2im = fft2((app.Image1));
            phase=angle(app.fft2im);
            % Taking the spectrum with log scaling
           
            app.fft2im = log(1+(abs(app.fft2im)));
            % Putting DC in the middle:
            spectrum = fftshift(app.fft2im);
            % finding maximum in spectrum:
            maximum = max(max(spectrum));
            % scaling maximum to 255 and minimum to 0:
            spectrum = 255*spectrum/maximum;
            % Casting to uint8 to be able to display:
            spectrum =  uint8(spectrum);
 
           imshow(phase,'parent',app.UIAxes17);
           imshow(spectrum,'parent',app.UIAxes16);

        end

        % Button pushed function: BoxFilterButton
        function BoxFilterButtonPushed(app, event)
         kernel_size=app.kernelsizeEditField.Value;
           [M,N]=size(app.Image1);
            if(kernel_size > M)
                warndlg("wrong kernel size")
            end
           try 
           
            kernal=(ones(kernel_size))/(kernel_size*kernel_size);
           
            
%             if(rem(M,2)==0)
%                 M=M+1;
%             end  
%             
%              if(rem(N,2)==0)
%                 N=N+1;
%              end
             
             %[M,N] = size(app.Image1);
            s=(kernel_size-1)/2;
            pad=zeros(M+s,N+s);
            for i=1:M
                for j=1:N
                    pad(i+s,j+s)=app.Image1(i,j);
                end
            end
            
            filtered_img=zeros(M,N);
           
             for i=1:size(pad,1)-(kernel_size-1)
                for j=1:size(pad,2)-(kernel_size-1)
                   
                    
                       filter=pad(i:i+(kernel_size-1),j:j+(kernel_size-1)).*kernal;
                       filtered_img(i,j)=sum(filter(:));
                      
                end
             end
             maa=max(filtered_img(:));
             filtered_img=filtered_img/maa;

          kernel=(ones(kernel_size))/(kernel_size*kernel_size);

%           I=zeros(M,N);
%           for i=1:M-1
%               for j=1:N-1
%                  I(i,j) = app.Image1(i,j);
%               end
%               
%           end
          Fourier_img=fft2(app.Image1);
          %test1=log(1+(abs(test1)));
%           padk=fftshift(fft2(kernel));
%           pad=padarray(kernel,(size(app.Image1)-size(kernel)));
           %[M,N]=size(app.Image1);
%           [m,n]=size(kernel_size);
           
           padkernel=zeros(M,N);
            for i=1:kernel_size
                for j=1:kernel_size
                    padkernel(i+(ceil((M-kernel_size)/2)),j+(ceil((N-kernel_size)/2)))=kernel(i,j);
                end
            end
           

          Fourier_padkernel=fft2(padkernel);
         
         
          Fourier_filter=Fourier_img.*Fourier_padkernel;
        
          ifourier_filter=ifft2(Fourier_filter);
          
          ifourier_filter=abs(ifourier_filter);
          m=max(ifourier_filter(:));
          result=ifourier_filter/m;
          
          
          disp(size(ifourier_filter))
          disp(size(Fourier_padkernel))
          if(size(result)==size(filtered_img))
              disp("true")
          else
              disp("false")
          end
          disp(size(filtered_img))


          result=ifftshift(result);

          difference=result-filtered_img;
          
          imshow(app.Image1,'parent',app.UIAxes19);
          imshow(filtered_img,'parent',app.UIAxes20);
          imshow(result,'parent',app.UIAxes21);
          imshow(difference,'parent',app.UIAxes22);
          catch
           warndlg('Invalid number, Try Again','Error'); 
          end
        end

        % Button pushed function: GuassianButton
        function GuassianButtonPushed(app, event)
%          try     
            kernel_size=app.kernelsizeEditField.Value; 
             sigma = kernel_size+1/6;
             mean = kernel_size/2;
             [M,N] = meshgrid(1:kernel_size);
             kernel = (1/(2*pi*sigma^2))*exp(-((M-mean).^2+(N-mean).^2)/(2*sigma^2));
             [h,w]=size(app.Image1);
             padkernel=zeros(h,w);
             
             padkernel(1:kernel_size, 1:kernel_size)= kernel;
             
             fftimg = fft2(double(app.Image1));
             fftkernel = fft2(padkernel);
             
             %fftkernel(fftkernel == 0)=1e-6;
             
             fftblur=fftimg.*fftkernel;
             gaussian=ifft2(fftblur);
             m=max(gaussian(:));
             gaussian=gaussian/m;
            
             imshow(gaussian,'parent',app.UIAxes21);


        end

        % Callback function
        function Button2Pushed4(app, event)
           imageSizeOfX = 256; %%Size of image
           imageSizeOfY = 256;
          [colInImage, rowsInImage] = meshgrid(1 : imageSizeOfX, 1 : imageSizeOfY); %% 2D grid coordinates
          x = colInImage;
          y = rowsInImage;
          centerOfX = 256/2; %%Center of circle
          centerOfY = 256/2;
          radius = 206; %%radius
          app.Pixels = ((y < radius & y>50 & x<radius &x>50)*150/256); %%logical matrix for a solid circle 
          circle=(((y - centerOfY).^2+ (x - centerOfX).^2 <= 50.^2)*230/256); 
          app.Final=app.Pixels+circle;
%           M = y-centerOfY;
%           N = x-centerOfX;
          for i=78:178
                for j=78:178
                    app.Pixels(i, j) = circle(i,j);
                end
           end
%           set(app.UIFigure, 'WindowButtonMotionFcn', @mouseMove);
          imshow(app.Pixels,'parent',app.UIAxes23); 
        end

        % Callback function
        function UIAxes23ButtonDown(app, event)
           disp("hi")
%            datacursormode (app.UIAxes23,'on');
%            ro=cursor_info;
%            disp(ro);
%            imageSizeOfX = 256; %%Size of image
%            imageSizeOfY = 256;
%           [colInImage, rowsInImage] = meshgrid(1 : imageSizeOfX, 1 : imageSizeOfY); %% 2D grid coordinates
%           x = colInImage;
%           y = rowsInImage;
%           centerOfX = 256/2; %%Center of circle
%           centerOfY = 256/2;
%           radius = 206; %%radius
%           app.Pixels = ((y < radius & y>50 & x<radius &x>50)*150/256); %%logical matrix for a solid circle 
%           circle=(((y - centerOfY).^2+ (x - centerOfX).^2 <= 50.^2)*230/256); 
%           app.Final=app.Pixels+circle;
%           imshow(app.Pixels,'parent',app.UIAxes23);
%           m=app.UIAxes23.PickableParts();
%           disp(m)
%           roiPOC = drawpoint(app.UIAxes23);
%             posPOC = get(roiPOC,'Position');
%             %delete(roiPOC);
%             disp(posPOC(1));
%             drawnow;
            roiPOC1 = drawpoint();
            posPOC1 = get(roiPOC1,'Position');
%              posPOC2 = get(roiPOC1(2,1),'Position');
            %delete(roiPOC);
            
            drawnow;
            disp(posPOC1(1));
            disp(posPOC1(2));
%             disp(posPOC2);
%             
%           Pixels = (posPOC.^2 > 0.5  );
%           imshow(Pixels,'parent',app.UIAxes23);
          
%           roi=drawrectangle(app.UIAxes23);
%           %roi = images.roi.Rectangle(app.UIAxes23);
%           imshow(roi,'parent',app.UIAxes23);
        end

        % Callback function
        function Button3Pushed2(app, event)
            %noise=random('Normal',0,5);
            %noise=noise/max(noise(:));
            
%             if(noise < 0)
%                mi=noise-min(noise);
%                noise=((mi)./max(mi));
%             end
            
            %imgnoise = app.Pixels+noise;
            %imshow(imgnoise,'parent',app.UIAxes23);
            
            
            %k=random('normal',0,5,size(app.ph));
            %k=normrnd(0,5,size(app.ph));
            k=((sqrt(5^2)*randn(size(app.ph)))+0);
%             mi=k-min(k);
%             k=((mi)./max(mi));
            %cm=uint8(cm);
            app.c=double(app.ph)+(k);
             app.c=uint8(app.c);
%             mi=app.c-min(app.c);
%             app.c=255*((mi)./max(mi));
            
            imshow(app.c,[],'parent',app.UIAxes24); 
            roi=drawrectangle(app.UIAxes24);
            roi_xy=roi.Position;
            x1=roi_xy(1);
            x2=x1+roi_xy(3);
            y1=roi_xy(2);
            y2=y1+roi_xy(4);

            app.region=app.c(x1:x2,x1:x2);
           imshow(app.region)
%              cm=double(cm);
%              c=cm+k;
%              imshow(c)
%              imshow(c,[])
             
        end

        % Callback function
        function Button4Pushed(app, event)
          %image = ones([256,256])*128;
          A = -10;
          B = 10;
          matrix_uniform = A + (B-A)*rand(size(app.ph));
%           
%            mi=matrix_uniform-min(matrix_uniform);
%            matrix_uniform=255*((mi)./max(mi));
            
          app.noisy_image = double(app.ph) + double(matrix_uniform);
          app.noisy_image=uint8(app.noisy_image);
          %noisy_image=noisy_image./max(noisy_image);
          imshow(app.noisy_image,[],'parent',app.UIAxes25);
           roi=drawrectangle(app.UIAxes25);
            roi_xy=roi.Position;
            x1=roi_xy(1);
            x2=x1+roi_xy(3);
            y1=roi_xy(2);
            y2=y1+roi_xy(4);

            app.region1=app.noisy_image(y1:y2,x1:x2);
           imshow(app.region1)
        end

        % Button pushed function: DisplayHistogramButton
        function DisplayHistogramButtonPushed(app, event)
         
            histogram_v = zeros(1,256);
            [M,N]=size(app.region);
            sum=0;
            std=0;
            count=0;
            for i = 1:M
            for j = 1:N
                
             histogram_v((app.region(i,j))+1) = histogram_v((app.region(i,j)) + 1)+1 ;  % getting gray level of each pixel 

            end
            
            for x=1:length(histogram_v)
                sum=sum+x*histogram_v(x);
                std=std+x^2*histogram_v(x);
                count=count+histogram_v(x);
            end
            end
%              disp(size(histogram_v));
             disp(count);
             disp(sum);
            mean=sum/count;
            E2=std/count;
            var=sqrt(E2-mean^2);
            disp(mean)
            app.MeanEditField.Value=mean;
            app.VarianceEditField.Value=var;
           %app.UIAxes6.Position=[27 ,576, 255, 255];
           %xaxis=0:;   
%             imshow(h,'parent',app.UIAxes26);
           bar(app.UIAxes26,histogram_v);
        end

        % Button pushed function: CreateImageButton
        function CreateImageButtonPushed(app, event)
            app.ph=ones(256,256,'uint8')*50;
            app.ph(40:220,40:220)=150;
            [M,N]=size(app.ph);
            [co,r]=meshgrid(1:M,1:N);
            center=128;
            radius=55;
            circle=(r-center).^2+(co-center).^2<=radius.^2;
            app.ph(circle)=250;
            imshow(app.ph,[],'parent',app.UIAxes28);
        end

        % Value changed function: ChooseNoiseDropDown
        function ChooseNoiseDropDownValueChanged2(app, event)
          value = app.ChooseNoiseDropDown.Value;
          if(strcmp(value,"Gaussian")==1)
             
            %guassian=((sqrt(5^2)*randn(size(app.ph)))+0);
             guassian = normrnd(0,5,size(app.ph));
            app.noisy_image=double(app.ph)+(guassian);
             app.noisy_image=uint8(app.noisy_image);
             
%               mi=app.noisy_image-min(app.noisy_image);
%              app.noisy_image=255*((mi)./max(mi));

             imshow(app.noisy_image,[],'parent',app.UIAxes29); 

%              

           
          elseif(strcmp(value,"Uniform")==1)
              
          A = -10;
          B = 10;
          matrix_uniform = A + (B-A)*rand(size(app.ph));
%           
%            mi=matrix_uniform-min(matrix_uniform);
%            matrix_uniform=255*((mi)./max(mi));
            
          app.noisy_image = double(app.ph) + double(matrix_uniform);
          app.noisy_image=uint8(app.noisy_image);
          %noisy_image=noisy_image./max(noisy_image);
          imshow(app.noisy_image,[],'parent',app.UIAxes29);
%          
%             
          elseif(strcmp(value,"Salt & Pepper")==1)
          a = 0.1; % 10% pixels altered
          b = 0.5; % 50% percent white pixels among all altered pixels
          n = numel(app.ph(:,:,1));
          m = fix(a*n);
          idx = randperm(n, m);
          k = fix(b*m);
          idx1 = idx(1:k);
          idx2 = idx(k+1:end);
          idx1 = idx1' + n.*(0:size(app.ph,3)-1);
          idx1 = idx1(:);
          idx2 = idx2' + n.*(0:size(app.ph,3)-1);
          idx2 = idx2(:);
          app.ph(idx1) = 255;
          app.ph(idx2) = 0;
          imshow(app.ph,[],'parent',app.UIAxes29);
          end
          
        end

        % Callback function
        function UIAxes27ButtonDown(app, event)
%             fig=figure();
% %            app.UIAxes27=app([tab 'UIAxes']);
%             ax=copyobj(app.UIAxes27,fig);
            roi=drawrectangle(app.UIAxes27,'XTick',[],'YTick',[]);
%             appROI=copyobj(roi,app.UIAxes27);
%             imshow(appROI,app.UIAxes27);
        end

        % Callback function
        function Button8Pushed(app, event)
            ROI=roipoly(app.noisy_image);
            %close(gcf);
            imshow(ROI,'Parent',app.UIAxes29);
        end

        % Button pushed function: CreateROIButton
        function CreateROIButtonPushed(app, event)
           fig=figure();
             appAxes=app.UIAxes29;
             ax=copyobj(appAxes,fig);
             roi=drawrectangle(ax,"InteractionsAllowed","none");
              appRoi=copyobj(roi,appAxes);
              roi_xy=roi.Position;
            x1=roi_xy(1);
            x2=x1+roi_xy(3);
            y1=roi_xy(2);
            y2=y1+roi_xy(4);
           
            app.region=app.noisy_image(y1:y2,x1:x2);
%              mi=app.region-min(app.region);
%              app.region=((mi)./max(mi));
        end

        % Button pushed function: CreatePhantomButton
        function CreatePhantomButtonPushed(app, event)
            phantomData = phantom('Modified Shepp-Logan',256);
            imshow(phantomData,'Parent',app.UIAxes30);
            thetas = 0:179;
            R = radon(phantomData);
            
            mi=R-min(R);
            R=(mi./max(mi));
            
            imshow(R,'Parent',app.UIAxes31);
            
            theta = [0,20,40,60,160];
            R2 = radon(phantomData,theta);
            mi=R2-min(R2);
            R2=(mi./max(mi));
            Laminogram = iradon(R2,theta,'linear','none');

            imshow(Laminogram,[],'Parent',app.UIAxes32);%'XData',xp([1,end]),'YData',[180,0]) % Fig. 5.20(b).
            
           
           laminogram_1 = iradon(R,thetas,'linear','none');%,thetas,'linear','none');
           imshow(laminogram_1,[],'Parent',app.UIAxes33);

           Ram_Lak = iradon(R,thetas,'linear','Ram-Lak');
           imshow(Ram_Lak,[],'Parent',app.UIAxes34);
           
           Hamming = iradon(R,thetas,'linear','Hamming');
           imshow(Hamming,[],'Parent',app.UIAxes35);
%             R3=((mi)./max(mi));
           
%             mi=f3-min(f3);
%             f3=255*((mi)./max(mi));


            %imshow(f4,'Parent',app.UIAxes34);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplayImageTab = uitab(app.TabGroup);
            app.DisplayImageTab.Title = 'Display Image ';
            app.DisplayImageTab.ButtonDownFcn = createCallbackFcn(app, @DisplayImageTabButtonDown, true);
            app.BrowseButton = uibutton(app.DisplayImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed2, true);
            app.BrowseButton.Icon = 'th (2).jpg';
            app.BrowseButton.Text = 'Browse';
            app.ImageInformationLabel = uilabel(app.DisplayImageTab);
            app.ImageInformationLabel.Text = 'Image Information';
            app.DicomInformationLabel = uilabel(app.DisplayImageTab);
            app.DicomInformationLabel.Text = 'Dicom Information';
            app.ImageDataPanel = uipanel(app.DisplayImageTab);
            app.ImageDataPanel.Title = 'Image Data';
            app.WidthTextAreaLabel = uilabel(app.ImageDataPanel);
            app.WidthTextAreaLabel.Text = 'Width';
            app.WidthTextArea = uitextarea(app.ImageDataPanel);
            app.HeightTextAreaLabel = uilabel(app.ImageDataPanel);
            app.HeightTextAreaLabel.Text = 'Height';
            app.HeightTextArea = uitextarea(app.ImageDataPanel);
            app.TotalSizeTextAreaLabel = uilabel(app.ImageDataPanel);
            app.TotalSizeTextAreaLabel.Text = 'Total Size';
            app.TotalSizeTextArea = uitextarea(app.ImageDataPanel);
            app.BitDepthTextAreaLabel = uilabel(app.ImageDataPanel);
            app.BitDepthTextAreaLabel.Text = 'BitDepth';
            app.BitDepthTextArea = uitextarea(app.ImageDataPanel);
            app.ImageColorTextAreaLabel = uilabel(app.ImageDataPanel);
            app.ImageColorTextAreaLabel.Text = 'Image Color';
            app.ImageColorTextArea = uitextarea(app.ImageDataPanel);
            app.DicomDataPanel = uipanel(app.DisplayImageTab);
            app.DicomDataPanel.Title = 'Dicom Data';
            app.PatientNameTextAreaLabel = uilabel(app.DicomDataPanel);
            app.PatientNameTextAreaLabel.Text = 'Patient Name';
            app.PatientNameTextArea = uitextarea(app.DicomDataPanel);
            app.PatienAgeTextAreaLabel = uilabel(app.DicomDataPanel);
            app.PatienAgeTextAreaLabel.Text = 'Patien Age';
            app.PatienAgeTextArea = uitextarea(app.DicomDataPanel);
            app.ModalityTextAreaLabel = uilabel(app.DicomDataPanel);
            app.ModalityTextAreaLabel.Text = 'Modality';
            app.ModalityTextArea = uitextarea(app.DicomDataPanel);
            app.ModalityTextArea.ValueChangedFcn = createCallbackFcn(app, @ModalityTextAreaValueChanged, true);
            app.BodyPartTextAreaLabel = uilabel(app.DicomDataPanel);
            app.BodyPartTextAreaLabel.Text = 'Body Part';
            app.BodyPartTextArea = uitextarea(app.DicomDataPanel);
            app.ConvertButton = uibutton(app.DisplayImageTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Icon = 'th (5).jpg';
            app.ConvertButton.Text = 'Convert';
            app.ImagePanel = uipanel(app.DisplayImageTab);
            app.ImagePanel.Title = 'Image';
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CreateWhiteImageTab = uitab(app.TabGroup);
            app.CreateWhiteImageTab.Title = 'Create White Image';
            app.UIAxes18 = uiaxes(app.CreateWhiteImageTab);
            title(app.UIAxes18, 'Title')
            xlabel(app.UIAxes18, 'X')
            ylabel(app.UIAxes18, 'Y')
            zlabel(app.UIAxes18, 'Z')
            app.CreatWhiteImageButton = uibutton(app.CreateWhiteImageTab, 'push');
            app.CreatWhiteImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreatWhiteImageButtonPushed3, true);
            app.CreatWhiteImageButton.Text = 'Creat White Image';
            app.ColorPixelsButton = uibutton(app.CreateWhiteImageTab, 'push');
            app.ColorPixelsButton.ButtonPushedFcn = createCallbackFcn(app, @ColorPixelsButtonPushed2, true);
            app.ColorPixelsButton.Text = 'Color Pixels';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.EntertheFactorEditFieldLabel = uilabel(app.InterpolationTab);
            app.EntertheFactorEditFieldLabel.Text = 'Enter the Factor';
            app.EntertheFactorEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.NearestNeighbourPanel = uipanel(app.InterpolationTab);
            app.NearestNeighbourPanel.Title = 'Nearest Neighbour';
            app.UIAxes3 = uiaxes(app.NearestNeighbourPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.BilinearPanel = uipanel(app.InterpolationTab);
            app.BilinearPanel.Title = 'Bilinear ';
            app.UIAxes4 = uiaxes(app.BilinearPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ButtonGroup = uibuttongroup(app.InterpolationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.NeighbourButton = uiradiobutton(app.ButtonGroup);
            app.NeighbourButton.Text = 'Neighbour';
            app.SelectInterpolaionButton = uiradiobutton(app.ButtonGroup);
            app.SelectInterpolaionButton.Text = 'Select Interpolaion';
            app.SelectInterpolaionButton.Value = true;
            app.BilinearButton = uiradiobutton(app.ButtonGroup);
            app.BilinearButton.Text = 'Bilinear';
            app.NewHeightEditFieldLabel = uilabel(app.InterpolationTab);
            app.NewHeightEditFieldLabel.Text = 'New Height';
            app.NewHeightEditField = uieditfield(app.InterpolationTab, 'text');
            app.NewWidthEditFieldLabel = uilabel(app.InterpolationTab);
            app.NewWidthEditFieldLabel.Text = 'New Width';
            app.NewWidthEditField = uieditfield(app.InterpolationTab, 'text');
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes7 = uiaxes(app.HistogramTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.HistogramTab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.HistogramTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.HistogramTab);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.NormalizedHistButton = uibutton(app.HistogramTab, 'push');
            app.NormalizedHistButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizedHistButtonPushed, true);
            app.NormalizedHistButton.Icon = 'th (3).jpg';
            app.NormalizedHistButton.Text = 'Normalized Hist';
            app.EqualizedimgButton = uibutton(app.HistogramTab, 'push');
            app.EqualizedimgButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizedimgButtonPushed, true);
            app.EqualizedimgButton.Icon = 'th (4).jpg';
            app.EqualizedimgButton.Text = 'Equalized img';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.UIAxes13 = uiaxes(app.FilteringTab);
            title(app.UIAxes13, 'After Filtering')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes14 = uiaxes(app.FilteringTab);
            title(app.UIAxes14, 'Before Scaling')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes15 = uiaxes(app.FilteringTab);
            title(app.UIAxes15, 'After Scaling')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            zlabel(app.UIAxes15, 'Z')
            app.Button = uibutton(app.FilteringTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EnterButton = uibutton(app.FilteringTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed4, true);
            app.EnterButton.Text = 'Enter';
            app.KernelvalueEditFieldLabel = uilabel(app.FilteringTab);
            app.KernelvalueEditFieldLabel.Text = 'Kernel value';
            app.KernelvalueEditField = uieditfield(app.FilteringTab, 'text');
            app.FactorEditFieldLabel = uilabel(app.FilteringTab);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.FilteringTab, 'text');
            app.KernelvalueEditField_2Label = uilabel(app.FilteringTab);
            app.KernelvalueEditField_2Label.Text = 'Kernel value';
            app.KernelvalueEditField_2 = uieditfield(app.FilteringTab, 'numeric');
            app.FactorEditField_2Label = uilabel(app.FilteringTab);
            app.FactorEditField_2Label.Text = 'Factor';
            app.FactorEditField_2 = uieditfield(app.FilteringTab, 'numeric');
            app.FourierTab = uitab(app.TabGroup);
            app.FourierTab.Title = 'Fourier';
            app.UIAxes16 = uiaxes(app.FourierTab);
            title(app.UIAxes16, 'Magnitude')
            xlabel(app.UIAxes16, 'X')
            ylabel(app.UIAxes16, 'Y')
            zlabel(app.UIAxes16, 'Z')
            app.UIAxes17 = uiaxes(app.FourierTab);
            title(app.UIAxes17, 'Phase')
            xlabel(app.UIAxes17, 'X')
            ylabel(app.UIAxes17, 'Y')
            zlabel(app.UIAxes17, 'Z')
            app.FourierTransformButton = uibutton(app.FourierTab, 'push');
            app.FourierTransformButton.ButtonPushedFcn = createCallbackFcn(app, @FourierTransformButtonPushed, true);
            app.FourierTransformButton.Text = 'Fourier Transform';
            app.FourierFilteringTab = uitab(app.TabGroup);
            app.FourierFilteringTab.Title = 'Fourier Filtering ';
            app.UIAxes19 = uiaxes(app.FourierFilteringTab);
            title(app.UIAxes19, 'Original Image')
            xlabel(app.UIAxes19, 'X')
            ylabel(app.UIAxes19, 'Y')
            zlabel(app.UIAxes19, 'Z')
            app.UIAxes20 = uiaxes(app.FourierFilteringTab);
            title(app.UIAxes20, 'Spatial Filter')
            xlabel(app.UIAxes20, 'X')
            ylabel(app.UIAxes20, 'Y')
            zlabel(app.UIAxes20, 'Z')
            app.UIAxes21 = uiaxes(app.FourierFilteringTab);
            title(app.UIAxes21, 'Fourier Filter')
            xlabel(app.UIAxes21, 'X')
            ylabel(app.UIAxes21, 'Y')
            zlabel(app.UIAxes21, 'Z')
            app.UIAxes22 = uiaxes(app.FourierFilteringTab);
            title(app.UIAxes22, 'Subtraction of filters')
            xlabel(app.UIAxes22, 'X')
            ylabel(app.UIAxes22, 'Y')
            zlabel(app.UIAxes22, 'Z')
            app.kernelsizeEditFieldLabel = uilabel(app.FourierFilteringTab);
            app.kernelsizeEditFieldLabel.Text = 'kernel size';
            app.kernelsizeEditField = uieditfield(app.FourierFilteringTab, 'numeric');
            app.BoxFilterButton = uibutton(app.FourierFilteringTab, 'push');
            app.BoxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BoxFilterButtonPushed, true);
            app.BoxFilterButton.Text = 'Box Filter';
            app.GuassianButton = uibutton(app.FourierFilteringTab, 'push');
            app.GuassianButton.ButtonPushedFcn = createCallbackFcn(app, @GuassianButtonPushed, true);
            app.GuassianButton.Text = 'Guassian ';
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.UIAxes26 = uiaxes(app.NoiseTab);
            title(app.UIAxes26, 'Title')
            xlabel(app.UIAxes26, 'X')
            ylabel(app.UIAxes26, 'Y')
            zlabel(app.UIAxes26, 'Z')
            app.Panel = uipanel(app.NoiseTab);
            app.Panel.Title = 'Panel';
            app.UIAxes28 = uiaxes(app.Panel);
            title(app.UIAxes28, 'Title')
            xlabel(app.UIAxes28, 'X')
            ylabel(app.UIAxes28, 'Y')
            zlabel(app.UIAxes28, 'Z')
            app.DisplayHistogramButton = uibutton(app.NoiseTab, 'push');
            app.DisplayHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayHistogramButtonPushed, true);
            app.DisplayHistogramButton.Text = 'Display Histogram';
            app.CreateImageButton = uibutton(app.NoiseTab, 'push');
            app.CreateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateImageButtonPushed, true);
            app.CreateImageButton.Text = 'Create Image';
            app.ChooseNoiseDropDownLabel = uilabel(app.NoiseTab);
            app.ChooseNoiseDropDownLabel.Text = 'Choose Noise';
            app.ChooseNoiseDropDown = uidropdown(app.NoiseTab);
            app.ChooseNoiseDropDown.Items = {'', 'Gaussian', 'Uniform', 'Salt & Pepper'};
            app.ChooseNoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseNoiseDropDownValueChanged2, true);
            app.ChooseNoiseDropDown.Value = '';
            app.MeanEditFieldLabel = uilabel(app.NoiseTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.NoiseTab, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.NoiseTab);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoiseTab, 'numeric');
            app.Panel2 = uipanel(app.NoiseTab);
            app.Panel2.Title = 'Panel2';
            app.UIAxes29 = uiaxes(app.Panel2);
            title(app.UIAxes29, 'Title')
            xlabel(app.UIAxes29, 'X')
            ylabel(app.UIAxes29, 'Y')
            zlabel(app.UIAxes29, 'Z')
            app.CreateROIButton = uibutton(app.NoiseTab, 'push');
            app.CreateROIButton.ButtonPushedFcn = createCallbackFcn(app, @CreateROIButtonPushed, true);
            app.CreateROIButton.Text = 'Create ROI';
            app.BackProjectionTab = uitab(app.TabGroup);
            app.BackProjectionTab.Title = 'Back Projection';
            app.UIAxes30 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes30, 'Title')
            xlabel(app.UIAxes30, 'X')
            ylabel(app.UIAxes30, 'Y')
            zlabel(app.UIAxes30, 'Z')
            app.UIAxes31 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes31, 'Title')
            xlabel(app.UIAxes31, 'X')
            ylabel(app.UIAxes31, 'Y')
            zlabel(app.UIAxes31, 'Z')
            app.UIAxes32 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes32, 'Title')
            xlabel(app.UIAxes32, 'X')
            ylabel(app.UIAxes32, 'Y')
            zlabel(app.UIAxes32, 'Z')
            app.UIAxes33 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes33, 'Title')
            xlabel(app.UIAxes33, 'X')
            ylabel(app.UIAxes33, 'Y')
            zlabel(app.UIAxes33, 'Z')
            app.UIAxes34 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes34, 'Title')
            xlabel(app.UIAxes34, 'X')
            ylabel(app.UIAxes34, 'Y')
            zlabel(app.UIAxes34, 'Z')
            app.UIAxes35 = uiaxes(app.BackProjectionTab);
            title(app.UIAxes35, 'Title')
            xlabel(app.UIAxes35, 'X')
            ylabel(app.UIAxes35, 'Y')
            zlabel(app.UIAxes35, 'Z')
            app.CreatePhantomButton = uibutton(app.BackProjectionTab, 'push');
            app.CreatePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePhantomButtonPushed, true);
            app.CreatePhantomButton.Text = 'Create Phantom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Openimage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end